classdef M_SLIP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLIP                            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewAnalysisMenu                 matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ModellingMenu                   matlab.ui.container.Menu
        OptionalMenu                    matlab.ui.container.Menu
        DetectedSSMenu                  matlab.ui.container.Menu
        FromExcelMenu                   matlab.ui.container.Menu
        TableCreationMenu               matlab.ui.container.Menu
        SLIPMenu                        matlab.ui.container.Menu
        HandinitialSrMenu               matlab.ui.container.Menu
        mEvaluationMenu                 matlab.ui.container.Menu
        FSevaluationMenu                matlab.ui.container.Menu
        ROCCurveMenu                    matlab.ui.container.Menu
        SusceptibilityMenu              matlab.ui.container.Menu
        RiskAssessmentMenu              matlab.ui.container.Menu
        MachineLearningMenu             matlab.ui.container.Menu
        FSClassificationMenu            matlab.ui.container.Menu
        HybridMenu                      matlab.ui.container.Menu
        FSClassificationMenu_2          matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        PlotSettingsMenu                matlab.ui.container.Menu
        StudyAreaMenu                   matlab.ui.container.Menu
        MunicipalitiesMenu              matlab.ui.container.Menu
        LandUseMenu                     matlab.ui.container.Menu
        AllMenu                         matlab.ui.container.Menu
        ExcludedMenu                    matlab.ui.container.Menu
        MorphologyMenu                  matlab.ui.container.Menu
        ElevationMenu                   matlab.ui.container.Menu
        SlopeMenu                       matlab.ui.container.Menu
        AspectMenu                      matlab.ui.container.Menu
        SoilMenu                        matlab.ui.container.Menu
        LithologyMapMenu                matlab.ui.container.Menu
        EffectiveCohesionMenu           matlab.ui.container.Menu
        FrictionAngleMenu               matlab.ui.container.Menu
        DrainageCoefficientMenu         matlab.ui.container.Menu
        AParameterMenu                  matlab.ui.container.Menu
        SoilUnitsMenu                   matlab.ui.container.Menu
        VegetationMenu                  matlab.ui.container.Menu
        MapMenu                         matlab.ui.container.Menu
        RootcohesionMenu                matlab.ui.container.Menu
        ThroughfallMenu                 matlab.ui.container.Menu
        DetectedSSMenu_2                matlab.ui.container.Menu
        SoilPropertiesMenu              matlab.ui.container.Menu
        RainfallMenu                    matlab.ui.container.Menu
        RecordingsMenu                  matlab.ui.container.Menu
        RecordingMapMenu_2              matlab.ui.container.Menu
        RecordingTrendMenu              matlab.ui.container.Menu
        ForecastMapMenu                 matlab.ui.container.Menu
        DistanceMenu                    matlab.ui.container.Menu
        RoadMenu                        matlab.ui.container.Menu
        SpecificLUMenu                  matlab.ui.container.Menu
        FSMenu                          matlab.ui.container.Menu
        FSMap                           matlab.ui.container.Menu
        PunctualMenu                    matlab.ui.container.Menu
        DISTANCESPanel                  matlab.ui.container.Panel
        LampDist                        matlab.ui.control.Lamp
        UITableSelLandUse               matlab.ui.control.Table
        OtherfromLandUseButton          matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        RoadFileDropDown                matlab.ui.control.DropDown
        RoadFileDropDownLabel           matlab.ui.control.Label
        UITableRainfall                 matlab.ui.control.Table
        UITableVeg                      matlab.ui.control.Table
        STARTButton                     matlab.ui.control.Button
        FolderLabel                     matlab.ui.control.Label
        CasestudyLabel                  matlab.ui.control.Label
        UITableLitho                    matlab.ui.control.Table
        UITableDTM                      matlab.ui.control.Table
        UITableLURem                    matlab.ui.control.Table
        UITableMun                      matlab.ui.control.Table
        RAINFALLPanel                   matlab.ui.container.Panel
        SelectfilesButton               matlab.ui.control.Button
        ForecastCheckBox                matlab.ui.control.CheckBox
        RecCheckBox                     matlab.ui.control.CheckBox
        SwitchRain                      matlab.ui.control.Switch
        LampInterp                      matlab.ui.control.Lamp
        InterpolationButton             matlab.ui.control.Button
        VEGETATIONPARAMETERSPanel       matlab.ui.container.ButtonGroup
        RemoveExistingAssociationButton  matlab.ui.control.Button
        ChoosefileVegDropDown           matlab.ui.control.DropDown
        ChoosefileDropDownLabel_3       matlab.ui.control.Label
        FinishedLampVeg1                matlab.ui.control.Lamp
        SelectFieldVegDropDown          matlab.ui.control.DropDown
        SelectFieldDropDown_2Label      matlab.ui.control.Label
        ExcelcreatedLabelVeg            matlab.ui.control.Label
        AssociationButtonVeg            matlab.ui.control.Button
        RunVeg                          matlab.ui.control.Button
        LandUseCheckBox                 matlab.ui.control.CheckBox
        SwitchVeg                       matlab.ui.control.Switch
        MORPHOLOGYPanel                 matlab.ui.container.Panel
        OrthophotoCheckBox              matlab.ui.control.CheckBox
        SelectDTMfilesButton            matlab.ui.control.Button
        LampMorph                       matlab.ui.control.Lamp
        RunDTMButton                    matlab.ui.control.Button
        EnterNewResolutionEditField     matlab.ui.control.NumericEditField
        EnterResolutionLabel            matlab.ui.control.Label
        ChangeResolutionCheckBox        matlab.ui.control.CheckBox
        LANDUSEPanel                    matlab.ui.container.Panel
        FinishedLamp_2                  matlab.ui.control.Lamp
        SelectButton                    matlab.ui.control.Button
        FinishedLamp                    matlab.ui.control.Lamp
        ImportLUButton                  matlab.ui.control.Button
        FieldLandUseDropDown            matlab.ui.control.DropDown
        FieldLandUseDropDownLabel       matlab.ui.control.Label
        SelectfileLUDropDown            matlab.ui.control.DropDown
        SelectfileLabel                 matlab.ui.control.Label
        STUDYAREAPanel                  matlab.ui.container.Panel
        WindowCheckBox                  matlab.ui.control.CheckBox
        FinishedLampRun1                matlab.ui.control.Lamp
        SelectMUNButton                 matlab.ui.control.Button
        RunSAButton                     matlab.ui.control.Button
        FieldMunicipalityDropDown       matlab.ui.control.DropDown
        FieldMunicipalityDropDownLabel  matlab.ui.control.Label
        SelectfileSADropDown            matlab.ui.control.DropDown
        StudyAreaLabel                  matlab.ui.control.Label
        SOILPARAMETERSButtonGroup       matlab.ui.container.ButtonGroup
        ChoosefileLitDropDown           matlab.ui.control.DropDown
        ChoosefileDropDownLabel_4       matlab.ui.control.Label
        FinishedLampSoil1               matlab.ui.control.Lamp
        AssociationButton               matlab.ui.control.Button
        ExcelcreatedLabel               matlab.ui.control.Label
        RunLito                         matlab.ui.control.Button
        SelectFieldDropDown             matlab.ui.control.DropDown
        SelectFieldDropDownLabel        matlab.ui.control.Label
        SwitchSoil                      matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: NewAnalysisMenu
        function NewAnalysisMenuSelected(app, event)
            try
               CaseName = string(inputdlg({'Case study name:'},'',1));
            
               app.CasestudyLabel.Text = strcat("Case study: ",CaseName);
               app.FolderLabel.Text = uigetdir('open');
            
               cd(app.FolderLabel.Text)
               drawnow;
               figure(app.SLIP)
            
               a_FolderCreation;
            
               app.STARTButton.Enable = 'on';
            
               uialert(app.SLIP, ...
                       strcat('Please fill the Raw Data subfolders (',fold_raw,')'), ...
                       'Warning')
            catch % me
               % getReport(me)
               uialert(app.SLIP, "Process not finished", "Warning")
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            
            app.SelectfileSADropDown.Enable = 'on';
            app.StudyAreaLabel.Enable = 'on';
            app.WindowCheckBox.Enable = 'on';
        end

        % Drop down opening function: SelectfileSADropDown
        function SelectfileSADropDownOpening(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_mun')
            
            cd(fold_raw_mun)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No Study Area shapefile in ",fold_raw_mun), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_mun, 'mode','multiple')
            end

            try
                FilesMun = {dir('*.shp').name};
                app.SelectfileSADropDown.Items = FilesMun;
                value = app.SelectfileSADropDown.Value;
                MunShapeFields = [{shapeinfo(value).Attributes.Name},"None of these"];
                app.FieldMunicipalityDropDown.Enable = 'on';
                app.FieldMunicipalityDropDownLabel.Enable = 'on';
                app.FieldMunicipalityDropDown.Items = MunShapeFields;
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: SelectMUNButton
        function SelectMUNButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_mun');     

            cd(fold_raw_mun);
            MunShape = shaperead(app.SelectfileSADropDown.Value);
            MunFieldName = app.FieldMunicipalityDropDown.Value;
            MunOpt = unique({MunShape.(MunFieldName)});
            choice3 = listdlg('PromptString',{'Choose municipality:',''},...
                              'ListString',MunOpt);

            figure(app.SLIP)
            drawnow

            MunSel = MunOpt(choice3);
            data = MunSel';
            set(app.UITableMun,'Data',data)

            app.RunSAButton.Enable = 'on';
        end

        % Button pushed function: RunSAButton
        function RunSAButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            Fig = app.SLIP;
            
            FileName_StudyArea = app.SelectfileSADropDown.Value;
            MunFieldName = app.FieldMunicipalityDropDown.Value;
            if MunFieldName ~= "None of these"
                MunSel = get(app.UITableMun,'Data');
            else
                MunFieldName = [];
                MunSel = [];
            end

            SpecificWindow = app.WindowCheckBox.Value;

            cd(app.FolderLabel.Text)
            try 
                A_StudyArea_Step1;
    
                app.SelectfileLabel.Enable = 'on';
                app.SelectfileLUDropDown.Enable = 'on';
                app.FieldLandUseDropDownLabel.Enable = 'on';
                app.FieldLandUseDropDown.Enable = 'on';
                app.FinishedLampRun1.Visible = 'on';           
                app.SelectDTMfilesButton.Enable = 'on';
                app.StudyAreaMenu.Enable = 'on';
                app.OrthophotoCheckBox.Enable = 'on';

            catch % me
                % getReport(me)
                uialert(app.SLIP, "Process not finished", "Warning")
            end

            figure(app.SLIP)
            drawnow
        end

        % Drop down opening function: SelectfileLUDropDown
        function SelectfileLUDropDownOpening(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_land_uses') 

            cd(fold_raw_land_uses)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No Land Uses shapefile in ",fold_raw_land_uses), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_land_uses, 'mode','multiple')
            end

            try
                FilesLand = {dir('*.shp').name};
                app.SelectfileLUDropDown.Items = FilesLand;
                value = app.SelectfileLUDropDown.Value;
                LandUseShapeFields = [{shapeinfo(value).Attributes.Name},"None of these"];
                app.FieldLandUseDropDown.Enable = 'on';
                app.FieldLandUseDropDownLabel.Enable = 'on';
                app.FieldLandUseDropDown.Items = LandUseShapeFields;
                app.FinishedLamp.Visible = 'on';
                app.ImportLUButton.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: ImportLUButton
        function ImportLUButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
                       
            FileNameLandUses = app.SelectfileLUDropDown.Value;
            LandUsesFieldName = app.FieldLandUseDropDown.Value;
            
            try
                Fig = app.SLIP;
                A_StudyArea_Step2;

                app.FinishedLamp.Color = [0 1 0];       
                app.LandUseMenu.Enable = 'on';
                app.AllMenu.Enable = 'on';
                app.SelectButton.Enable = 'on';
                app.SelectDTMfilesButton.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: SelectButton
        function RemoveLUButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            cd(fold_var)
            load('LandUsesVariables','AllLandUnique');

            try
                cd(fold_user)
                SheetLuAss = readcell('LandUsesAssociation.xlsx', 'Sheet','Association');
                AllLandUniqueAbbr = SheetLuAss(2:end,2)';
                Choice = listdlg('PromptString',{'Choose land uses to remove:',''}, ...
                                 'ListString',AllLandUniqueAbbr);
    
                figure(app.SLIP)
                drawnow
    
                LandUseToRemoveSel = AllLandUnique(Choice);
                
                Data = LandUseToRemoveSel';
                set(app.UITableLURem, 'Data', Data);
    
                Fig = app.SLIP;
                A_StudyArea_Step3;
    
                app.FinishedLamp_2.Visible = 'on';
                app.ExcludedMenu.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP, "Process not finished", "Warning")
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: SelectDTMfilesButton
        function SelectDTMfilesButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_dtm');
            
            cd(fold_raw_dtm)

            if isempty([{dir('*.tif').name}, {dir('*.asc').name},{dir('*.img').name}])
                Ans = uiconfirm(app.SLIP, strcat("No rasters in ",fold_raw_dtm), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory({'tif','tfw','asc'}, fold_raw_dtm, 'mode','multiple')
            end

            try
                Files = sort(string([{dir('*.tif').name}, ...
                                     {dir('*.tfw').name}, ...
                                     {dir('*.asc').name},...
                                     {dir('*.img').name}]));
                Choice = listdlg('PromptString',{'Choose a file:',''}, ...
                                  'ListString',Files);
                FileName_DTM = string(Files(Choice));

                figure(app.SLIP)
                drawnow
                
                data = FileName_DTM';
                set(app.UITableDTM, 'Data', data);
                
                app.ChangeResolutionCheckBox.Enable = 'on';
                app.RunDTMButton.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Value changed function: ChangeResolutionCheckBox
        function ChangeResolutionCheckBoxValueChanged(app, event)
            value = app.ChangeResolutionCheckBox.Value;
            if value == 1
                app.EnterNewResolutionEditField.Editable = 'on';
                app.EnterNewResolutionEditField.Enable = 'on';
            else
                app.EnterNewResolutionEditField.Editable = 'off';
                app.EnterNewResolutionEditField.Enable = 'off';
            end
        end

        % Button pushed function: RunDTMButton
        function RunDTMButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            
            cd(fold_var)
            if exist('UserMorph_Answers.mat')
                Answer = uiconfirm(app.SLIP, ...
                                   ['DTM has been already processed. ' ...
                                    'Do you want to overwrite existing data?'], ...
                                   'Existing DTM data', ...
                                   'Options',{'Yes, thanks', 'No, for God!'}, ...
                                   'DefaultOption',2);
                switch Answer
                    case 'Yes, thanks'
                        
                    case 'No, for God!'
                        load('UserMorph_Answers.mat');              
                        DTMData = [FileName_DTM];
                        set(app.UITableDTM, 'Data',DTMData);
                        
                        if AnswerChangeDTMResolution == 1
                            app.ChangeResolutionCheckBox.Enable = 'on';
                            app.ChangeResolutionCheckBox.Value = 1;
                            
                            app.EnterNewResolutionEditField.Enable = 'on';
                            app.EnterNewResolutionEditField.Editable = 'on';
                            app.EnterNewResolutionEditField.Value = NewDx;
                        end
                        return                        
                end
            end
            
            AnswerChangeDTMResolution = app.ChangeResolutionCheckBox.Value;
            FileName_DTM = get(app.UITableDTM,'Data');

            if any(contains(FileName_DTM,'tif')) && any(contains(FileName_DTM,'tfw'))
                DTMType = 0;
%             elseif all(contains(FileName_DTM,'tif'))
%                 DTMType = 1;
%             elseif all(contains(FileName_DTM,'asc'))
%                 DTMType = 2;
            else
                DTMType = 1;
            end

            if AnswerChangeDTMResolution == 1
                NewDx = app.EnterNewResolutionEditField.Value;
                NewDy = NewDx;
            end

            OrthophotoAnswer = app.OrthophotoCheckBox.Value;

            try
                Fig = app.SLIP;
                B_Morphology;
    
                app.LampMorph.Visible = 'on';
                app.MorphologyMenu.Enable = 'on';
                app.ElevationMenu.Enable = 'on';
                app.SlopeMenu.Enable = 'on';
                app.AspectMenu.Enable = 'on';  
                app.SwitchSoil.Enable = 'on';
                app.RunLito.Enable = 'on';
            catch me
                 getReport(me)
                 uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                    'an error occurred!'], 'Generic Error')
            end

            try
                cd(fold_raw_road)
                FilesRoad = {dir('*.shp').name};
                app.RoadFileDropDown.Enable = 'on';
                app.EvaluateButton.Enable = 'on';
                app.OtherfromLandUseButton.Enable = 'on';
                app.RoadFileDropDown.Items = FilesRoad;
            catch me
                 getReport(me)
                 uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                    'an error occurred!'], 'Generic Error')
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Value changed function: SwitchSoil
        function SwitchSoilValueChanged(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_lit');
            
            value = app.SwitchSoil.Value;
            if strcmp(value,'Map')
               app.ChoosefileLitDropDown.Enable = 'on';
               app.SelectFieldDropDown.Enable = 'on';
            elseif strcmp(value,'Uniform')
                app.ChoosefileLitDropDown.Enable = 'off';
                app.SelectFieldDropDown.Enable = 'off';
                app.ExcelcreatedLabel.Visible = 'off';
                app.AssociationButton.Visible = 'off';
            else
                uialert(app.SLIP,['Error in soil parameters type switch, ' ...
                                  'not "Map" or "Uniform"'],'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Drop down opening function: ChoosefileLitDropDown
        function ChoosefileLitDropDownOpening(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_lit')

            cd(fold_raw_lit)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No litho shapefile in ",fold_raw_lit), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_lit, 'mode','multiple')
            end

            try
                FilesLit = {dir('*.shp').name};
                app.ChoosefileLitDropDown.Items = FilesLit;
                value = app.ChoosefileLitDropDown.Value;
                LitShapeFields = [{shapeinfo(value).Attributes.Name},"None of these"];
                app.SelectFieldDropDown.Enable = 'on';
                app.SelectFieldDropDown.Items = LitShapeFields;
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: RunLito
        function RunLitoPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_lit','fold_var','fold_user','fold0')

            app.FinishedLampSoil1.Visible = 'on';

            Fig = app.SLIP;
            
            if strcmp(app.SwitchSoil.Value,'Uniform')
                AnswerAttributionSoilParameter = 0;

                try
                    C_Soil_ParametersAttribution_Step0;
                catch me
                    getReport(me)
                    uialert(app.SLIP,'Please see MATLAB command, an error occurred!', ...
                                     'Generic Error')
                end

                app.SoilMenu.Enable = 'on';
                app.FrictionAngleMenu.Enable = 'on';
                app.EffectiveCohesionMenu.Enable = 'on';
                app.DrainageCoefficientMenu.Enable='on';
                app.AParameterMenu.Enable='on';
                app.SwitchVeg.Enable = 'on';
                app.VEGETATIONPARAMETERSPanel.Enable = 'on';
            elseif strcmp(app.SwitchSoil.Value,'Map')
                AnswerAttributionSoilParameter = 1;
                FileName_Lithology = app.ChoosefileLitDropDown.Value;
                LitFieldName = app.SelectFieldDropDown.Value;

                try
                    Fig = app.SLIP;
                    C_Soil_ParametersAttribution_Step1;
                catch me
                    getReport(me)
                    uialert(app.SLIP,'Please see MATLAB command, an error occurred!', ...
                                     'Generic Error')
                end

                app.ExcelcreatedLabel.Visible = 'on';
                app.AssociationButton.Visible = 'on';             
            end

            app.FinishedLampSoil1.Color = [0 1 0];

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: AssociationButton
        function AssociationButtonLithoPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_var','fold_user','fold0')

            try
                Fig = app.SLIP;
                C_Soil_ParametersAttribution_Step2;   
                data=[LUAbbr LU2DSC(SelectedSoil)];
                set(app.UITableLitho,'Data',data)

                app.SoilMenu.Enable='on';
                app.LithologyMapMenu.Enable='on';
                app.SoilUnitsMenu.Enable='on';

                app.FrictionAngleMenu.Enable='on';
                app.EffectiveCohesionMenu.Enable='on';
                app.DrainageCoefficientMenu.Enable='on';
                app.AParameterMenu.Enable='on';

                app.SwitchVeg.Enable='on';
                app.VEGETATIONPARAMETERSPanel.Enable='on';
            catch me
                switch me.message
                    case 'L 1'
                        uialert(app.SLIP,strcat("Excel LuDSCAssociation in ", fold_user," is not filled"),...
                            'Error L 1')
                    otherwise
                        uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
                        getReport(me)
                end
            end

            figure(app.SLIP)
            drawnow
        end

        % Value changed function: SwitchVeg
        function SwitchVegValueChanged(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_raw_veg');
            
            app.LandUseCheckBox.Value=0;            
            value = app.SwitchVeg.Value;         
            if strcmp(value,'Map')
               app.LandUseCheckBox.Enable='on';
               app.ChoosefileVegDropDown.Enable='on';
               app.SelectFieldVegDropDown.Enable='on';
            else
               app.ChoosefileVegDropDown.Visible='off';
               app.SelectFieldVegDropDown.Visible='off';
               app.LandUseCheckBox.Enable='off';
            end
        end

        % Value changed function: LandUseCheckBox
        function LandUseCheckBoxValueChanged(app, event)
            value = app.LandUseCheckBox.Value;
            if value==1
                app.ChoosefileVegDropDown.Enable='off';
                app.SelectFieldVegDropDown.Enable='off';
                app.SelectFieldVegDropDown.Items={app.FieldLandUseDropDown.Value};
                app.ChoosefileVegDropDown.Items={app.SelectfileLUDropDown.Value};
            end
        end

        % Drop down opening function: ChoosefileVegDropDown
        function ChoosefileVegDropDownOpening(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_raw_veg')
            
            cd(fold_raw_veg)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No vegetation shapefile in ",fold_raw_veg), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_veg, 'mode','multiple')
            end

            try
                if not(app.LandUseCheckBox.Value)
                    FilesVeg = {dir('*.shp').name};
                    app.ChoosefileVegDropDown.Items = FilesVeg;
                end
                value = app.ChoosefileVegDropDown.Value;
                VegShapeFields = [{shapeinfo(value).Attributes.Name},"None of these"];
                app.SelectFieldVegDropDown.Enable = 'on';
                app.SelectFieldVegDropDown.Items = VegShapeFields;
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: RunVeg
        function RunVegPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders')

            app.FinishedLampVeg1.Visible = 'on';

            Fig = app.SLIP;

            if strcmp(app.SwitchVeg.Value, 'Uniform')

                AnswerAttributionVegetationParameter = 0;
                C_Vegetation_ParameterAttribution_Step0;
                
                app.VegetationMenu.Enable = 'on';
                app.RootcohesionMenu.Enable = 'on';
                app.ThroughfallMenu.Enable = 'on';

            elseif strcmp(app.SwitchVeg.Value, 'Map')

                if app.LandUseCheckBox.Value == 1
                    FileName_Vegetation = app.SelectfileLUDropDown.Value;
                    AnswerAttributionVegetationParameter = 2;
                    C_Vegetation_ParameterAttribution_Step1_LU;
                else
                    FileName_Vegetation = app.ChoosefileVegDropDown.Value;                                   
                    VegFieldName = app.SelectFieldVegDropDown.Value;
                    AnswerAttributionVegetationParameter = 1;
                    C_Vegetation_ParameterAttribution_Step1;
                end

                app.ExcelcreatedLabelVeg.Visible = 'on';
                app.AssociationButtonVeg.Enable = 'on';
                app.VegetationMenu.Enable = 'on';
                app.MapMenu.Enable = 'on';

            end
            
            app.FinishedLampVeg1.Color = [0 1 0];
        end

        % Button pushed function: AssociationButtonVeg
        function AssociationButtonVegPushed(app, event)
            cd(app.FolderLabel.Text)

            load('os_folders','fold_var','fold_user','fold0');

            try
                Fig = app.SLIP;
                C_Vegetation_ParameterAttribution_Step2;
                data = [VUAbbr VU2DVC(SelectedVeg)];
                set(app.UITableVeg, 'Data',data);
                app.RootcohesionMenu.Enable = 'on';
                app.ThroughfallMenu.Enable  = 'on';
            catch me
                switch me.message
                    case 'V 1'
                        uialert(app.SLIP, strcat("Excel VuDVCAssociation in ", ...
                                fold_user," is not filled"), 'Error V 1')
                    otherwise
                        getReport(me)
                        uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                           'an error occurred!'],'Generic Error')
                end
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ElevationMenu
        function ElevationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders','fold_var','fold0','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=1;
            xyz_Plot_Morphology;
        end

        % Menu selected function: SlopeMenu
        function SlopeMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders','fold_var','fold0','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=2;
            xyz_Plot_Morphology;
        end

        % Menu selected function: AspectMenu
        function AspectMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders','fold_var','fold0','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=3;
            xyz_Plot_Morphology;
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')
            Fig = app.SLIP;
            NumFigPlot = 1;
            xyz_Plot_LandUse;
        end

        % Menu selected function: ExcludedMenu
        function ExcludedMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders')
            Fig = app.SLIP;
            NumFigPlot=2;
            xyz_Plot_LandUse;
        end

        % Menu selected function: LithologyMapMenu
        function LithologyMapMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=1;
            xyz_Plot_SoilParameters;
        end

        % Menu selected function: EffectiveCohesionMenu
        function EffectiveCohesionMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=2;
            xyz_Plot_SoilParameters;            
        end

        % Menu selected function: FrictionAngleMenu
        function FrictionAngleMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig')
            Fig = app.SLIP;
            NumFigPlot=3;
            xyz_Plot_SoilParameters;
        end

        % Value changed function: FieldMunicipalityDropDown
        function FieldMunicipalityDropDownValueChanged(app, event)
            MunFieldName = app.FieldMunicipalityDropDown.Value;
            if MunFieldName=="None of these"
                app.SelectMUNButton.Enable='off';
                app.RunSAButton.Enable='on';
            else
                app.SelectMUNButton.Enable='on';
            end
        end

        % Button pushed function: InterpolationButton
        function InterpolationButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_var','fold0','sl');

            Fig = app.SLIP;

            try 
                 D_TimeSensitive_Interpolation_Step2;
                 app.LampInterp.Visible='on';
            
                 if AnalysisCase=='SLIP'
                     app.SLIPMenu.Enable='on';
                     app.HandinitialSrMenu.Enable='on';
                     app.mEvaluationMenu.Enable='on';
            
                 end

                 app.RainfallMenu.Enable='on';       

            catch me
                 getReport(me)
                 uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                    'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: HandinitialSrMenu
        function HandinitialSrMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_var');

            cd(fold_var)
            load('UserTimeSens_Answers.mat')
            
            if exist('Sr0')
                SuggestedValue={num2str(Sr0),num2str(H)};
            else
                SuggestedValue={'0.55','1.2'};
            end

            % Attribution of Sr0 and H
            choice3=inputdlg({'Initial saturation Sr0 (-):',...
                  'Depth of topsoil H (meters):'},'',1,SuggestedValue);
            Sr0=eval(choice3{1});
            H=eval(choice3{2});

            % Creating cell array with name of variables that will be stored
            save('UserTimeSens_Answers.mat','Sr0','H','-append')
        end

        % Menu selected function: mEvaluationMenu
        function mEvaluationMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            Fig=app.SLIP;

            try
                E_SLIP_Step1_mEvaluation;
                app.FSevaluationMenu.Enable='on';
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FSevaluationMenu
        function FSevaluationMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_var','fold0','fold_res_fs','sl');
            E_SLIP_Step2_FS;
            app.FSClassificationMenu.Enable = 'on';
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.FolderLabel.Text=uigetdir('open');
            cd(app.FolderLabel.Text);
            drawnow;
            figure(app.SLIP)
            
            load('os_folders','sl','CaseName')
            a_FolderCreation;
            
            app.CasestudyLabel.Text=strcat("Case study: ",CaseName);
            app.STARTButton.Enable=true;

            cd(fold_var)
            
            if exist('UserStudyArea_Answers.mat')
                load('UserStudyArea_Answers.mat');
                cd(fold_raw_mun)
                FilesMun={dir('*.shp').name};
                app.STUDYAREAPanel.Enable='on';
                app.SelectfileSADropDown.Enable='on';
                app.SelectfileSADropDown.Items=FilesMun;
                app.SelectfileSADropDown.Value=FileName_StudyArea;

                app.FieldMunicipalityDropDown.Enable='on';
                MunShapeFields=[{shapeinfo(FileName_StudyArea).Attributes.Name},...
                    "None of these"];
                app.FieldMunicipalityDropDownLabel.Enable='on';
                app.FieldMunicipalityDropDown.Items=MunShapeFields;
                if isempty(MunFieldName); MunFieldName= "None of these"; end
                app.FieldMunicipalityDropDown.Value = MunFieldName;
                app.SelectMUNButton.Enable='on';
                app.RunSAButton.Enable='on';
                data=[MunSel];
                set(app.UITableMun,'Data',data)
                cd(fold_var)

                app.WindowCheckBox.Enable='on';
                app.WindowCheckBox.Value=SpecificWindow;
                app.StudyAreaMenu.Enable = 'on';

            end

            if exist('LandUsesVariables.mat')
                load('UserStudyArea_Answers.mat', 'FileNameLandUses', 'LandUsesFieldName');
                load('LandUsesVariables.mat');

                cd(fold_raw_land_uses)
                FilesLand={dir('*.shp').name};

                app.LANDUSEPanel.Enable='on';
                app.SelectfileLUDropDown.Enable='on';
                app.SelectfileLUDropDown.Items=FilesLand;
                app.SelectfileLUDropDown.Value = FileNameLandUses;

                app.FieldLandUseDropDown.Enable='on';
                LandUsesShapeFields=[{shapeinfo(FileNameLandUses).Attributes.Name}, "None of these"];
                app.FieldLandUseDropDownLabel.Enable='on';
                app.FieldLandUseDropDown.Items=LandUsesShapeFields;
                app.FieldLandUseDropDown.Value=LandUsesFieldName;

                app.LandUseMenu.Enable='on';
                app.AllMenu.Enable='on';

                try 
                    data2=[AllLandUnique(IndexLandUsesToRemove)]';
                    set(app.UITableLURem,'Data',data2)
                    app.ExcludedMenu.Enable='on';
                end

                app.ImportLUButton.Enable='on';
                app.SelectButton.Enable='on';

                app.SelectDTMfilesButton.Enable='on';
                cd(fold_var)       
            end


            if exist('UserMorph_Answers.mat')
                load('UserMorph_Answers.mat')
                
                DTMData = [FileName_DTM];
                set(app.UITableDTM, 'Data',DTMData)

                if AnswerChangeDTMResolution == 1
                    app.ChangeResolutionCheckBox.Enable = 'on';
                    app.ChangeResolutionCheckBox.Value = 1;

                    app.EnterNewResolutionEditField.Enable = 'on';
                    app.EnterNewResolutionEditField.Editable = 'on';
                    app.EnterNewResolutionEditField.Value = NewDx;

                end
                
                app.SelectDTMfilesButton.Enable = 'on';
                app.OrthophotoCheckBox.Enable = 'on';

                if exist('Orthophoto.mat')
                    app.OrthophotoCheckBox.Value = 1;
                end

                app.SwitchSoil.Enable = 'on';
                app.RunLito.Enable = 'on';

                app.MorphologyMenu.Enable = 'on';
                app.ElevationMenu.Enable = 'on';
                app.SlopeMenu.Enable = 'on';
                app.AspectMenu.Enable = 'on';

                app.RunDTMButton.Enable = 'on';
                
                app.DISTANCESPanel.Enable = 'on';
                app.RoadFileDropDown.Enable = 'on';
                app.EvaluateButton.Enable = 'on';
                app.OtherfromLandUseButton.Enable = 'on';
                
                cd(fold_raw_road)
                FilesRoad = {dir('*.shp').name};
                app.RoadFileDropDown.Items = FilesRoad; 
                cd(fold_var)

            end


            if exist('UserSoil_Answers.mat')
                load('UserSoil_Answers.mat')

                if AnswerAttributionSoilParameter==1
                    
                    app.SwitchSoil.Value='Map';
                    app.ChoosefileLitDropDown.Enable='on';
                    app.SelectFieldDropDown.Enable='on';
                    app.SelectFieldDropDownLabel.Enable='on';
                    
                    cd(fold_raw_lit)
                    FilesLit={dir('*.shp').name};
                    app.ChoosefileLitDropDown.Items=FilesLit;
                    app.ChoosefileLitDropDown.Value=FileName_Lithology;

                    LitShapeFields=[{shapeinfo(FileName_Lithology).Attributes.Name},...
                        "None of these"];
                    
                    app.SelectFieldDropDown.Items=LitShapeFields;
                    
                    app.SelectFieldDropDown.Value=LitFieldName;

                    app.RunLito.Enable='on';
                    app.AssociationButton.Visible='on';
                    
                    app.SoilMenu.Enable='on';
                    app.LithologyMapMenu.Enable='on';
                    
                    cd(fold_var)
                    try 
                        load('LUDSCMapParameters.mat','LUAbbr','LU2DSC','SelectedSoil')
                        app.EffectiveCohesionMenu.Enable='on';
                        app.FrictionAngleMenu.Enable='on';
                        app.DrainageCoefficientMenu.Enable='on';
                        app.AParameterMenu.Enable='on';
                        app.SoilUnitsMenu.Enable='on';
                        
                        data=[LUAbbr LU2DSC(SelectedSoil)];
                        set(app.UITableLitho,'Data',data);

                        app.VEGETATIONPARAMETERSPanel.Enable='on';

                        app.SwitchVeg.Enable='on';
                        app.LandUseCheckBox.Enable='on';
                        app.RunVeg.Enable='on';

                    end
                else
                    app.SoilMenu.Enable='on';
                    app.EffectiveCohesionMenu.Enable='on';
                    app.FrictionAngleMenu.Enable='on';
                    app.DrainageCoefficientMenu.Enable='on';
                    app.AParameterMenu.Enable='on';

                    app.VEGETATIONPARAMETERSPanel.Enable='on';

                    app.SwitchVeg.Enable='on';
                    app.LandUseCheckBox.Enable='on';
                    app.RunVeg.Enable='on';
                end
                
            end


             if exist('UserVeg_Answers.mat')
                load('UserVeg_Answers.mat')
                
                app.VegetationMenu.Enable='on';
                app.VEGETATIONPARAMETERSPanel.Enable='on';

                if VegAttribution == 0
                    
                    app.SwitchVeg.Value='Uniform';
                    app.RootcohesionMenu.Enable='on';
                    app.ThroughfallMenu.Enable='on';

                else

                    if AnswerAttributionVegetationParameter == 1
                        app.SwitchVeg.Value='Map';
                    else
                        app.SwitchVeg.Value='Uniform';
                    end
                    app.ChoosefileVegDropDown.Enable='on';
                    app.SelectFieldVegDropDown.Enable='on';

                    if AnswerAttributionVegetationParameter == 1 
                        cd(fold_raw_veg)
        
                    elseif AnswerAttributionVegetationParameter == 2 
                        cd(fold_raw_land_uses)
                        app.LandUseCheckBox.Enable='on';
                        app.LandUseCheckBox.Value=1;
                    end

                    if AnswerAttributionVegetationParameter ~= 0
                        FilesVeg={dir('*.shp').name};  
                        app.ChoosefileVegDropDown.Items = FilesVeg;
                        app.ChoosefileVegDropDown.Value = FileName_Vegetation;
    
                        VegShapeFields = [{shapeinfo(FileName_Vegetation).Attributes.Name}, "None of these"];
                        app.SelectFieldVegDropDown.Enable = 'on';
                        app.SelectFieldVegDropDown.Items = VegShapeFields;
                        app.SelectFieldVegDropDown.Value = VegFieldName;
                    end

                    app.RunVeg.Enable = 'on';
                    app.AssociationButtonVeg.Enable = 'on';
                    
                    app.MapMenu.Enable = 'on';
                    
                    cd(fold_var)
                    try 
                        load('VUDVCMapParameters.mat','VUAbbr','VU_DVC','SelectedVeg')
                        app.RootcohesionMenu.Enable='on';
                        app.ThroughfallMenu.Enable='on';
                        
                        data=[VUAbbr cell2mat(VU_DVC{2})];
                        set(app.UITableVeg,'Data',data)

                    end    
                end
                
                cd(fold_raw_rain)
                FilesRain={dir('*.xlsx').name};
                cd(fold_var)
             end

             if exist('UserTimeSens_Answers.mat')
                load('UserTimeSens_Answers.mat','AnalysisCase','AnswerRainfallRec',...
                     'AnswerRainfallFor')
                
                app.RainfallMenu.Enable='on';

                app.SwitchRain.Value=AnalysisCase;
                app.RecCheckBox.Value=AnswerRainfallRec;
                app.ForecastCheckBox.Value=AnswerRainfallFor;

                if AnswerRainfallRec==1
                    app.RecordingsMenu.Enable='on';
                    RecAlreadyInterp=isprop(matfile('RainInterpolated.mat'),...
                                                    'RainInterpolated');
                end

                if AnswerRainfallFor==1
                    app.ForecastMapMenu.Enable='on';
                end

                if strcmp(AnalysisCase,'SLIP')
                    app.HandinitialSrMenu.Enable='on';
                    app.mEvaluationMenu.Enable='on';
                    load('AnalysisInformation.mat','StabilityAnalysis');
                    RainfallEvents=string(StabilityAnalysis{:,2});
                    app.FSClassificationMenu.Enable = 'on';
                end

                data=[RainfallEvents'];
                set(app.UITableRainfall,'Data',data);
             end

             if exist('Distance.mat')
                 app.UITableSelLandUse.Enable='on';
                 load('Distance.mat','SelectedLU4Dist')
                 data=[SelectedLU4Dist];
                set(app.UITableSelLandUse,'Data',data);
             end
             
             cd(fold0)
        end

        % Menu selected function: MapMenu
        function MapMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var','fold0','fold_fig');
            
            Fig = app.SLIP;
            NumFigPlot = 1;

            try
                xyz_Plot_VegetationParameters;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RootcohesionMenu
        function RootcohesionMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var','fold0','fold_fig');

            Fig = app.SLIP;
            NumFigPlot = 2;

            try
                xyz_Plot_VegetationParameters;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow    
        end

        % Menu selected function: ThroughfallMenu
        function ThroughfallMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var','fold0','fold_fig');

            Fig = app.SLIP;
            NumFigPlot = 3;

            try
                xyz_Plot_VegetationParameters;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow    
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_rain','fold_raw_rain_for','sl','fold_var','fold0');
            
            if app.RecCheckBox.Value == 0 && app.ForecastCheckBox.Value == 0
                uialert(app.SLIP, 'The choice of the rainfall type is mandatory', 'Error')
                return
            elseif (strcmp(app.SwitchRain.Value,'SLIP') && ...
                    app.RecCheckBox.Value == 0          && ...
                    app.ForecastCheckBox.Value == 1)
                uialert(app.SLIP, 'Recordings are necessary for a forecast analysis', 'Error')
                return
            elseif (strcmp(app.SwitchRain.Value,'Other') && ...
                    app.RecCheckBox.Value == 1           && ...
                    app.ForecastCheckBox.Value == 1)
                uialert(app.SLIP, 'Please select just one rainfall type', 'Error')
                return
            end

            AnalysisCase = app.SwitchRain.Value;
            AnswerRainfallRec = 0;
            AnswerRainfallFor = 0;

            if app.RecCheckBox.Value == 1
                AnswerRainfallRec = 1;
            end

            if app.ForecastCheckBox.Value == 1
                AnswerRainfallFor = 1;
            end

            try 

                Fig = app.SLIP;
                D_TimeSensitive_DataReading_Step1;
                Data = string(datestr(RainfallEvents));
                set(app.UITableRainfall, 'Data',Data);

                switch AnalysisCase
                    case 'SLIP'
                        app.UITableRainfall.ColumnName = 'Analysis events';
                    case 'Other'
                        app.UITableRainfall.ColumnName = 'Rainfall events';
                end

            catch me

                switch me.message
                    case 'DT 1'
                        uialert(app.SLIP, ['Forecast and recordings have ' ...
                                           'incopatible dates'], 'Error DT1')
                    otherwise
                        getReport(me)
                        uialert(app.SLIP,['Please see MATLAB command, an ' ...
                                          'error occurred!'], 'Generic Error')
                end

            end
            
            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RecordingMapMenu_2
        function RecordingMapMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            
            Fig = app.SLIP;
            RainFallType = 1;

            try
                xyz_Plot_Rain;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ForecastMapMenu
        function ForecastMapMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders')

            Fig = app.SLIP;
            RainFallType=0;

            try
                xyz_Plot_Rain;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FromExcelMenu
        function FromExcelMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            cd(fold_raw_det_ss)

            if isempty({dir('*.xlsx').name})
                Ans = uiconfirm(app.SLIP, strcat("No excel in ",fold_raw_det_ss), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('xlsx', fold_raw_det_ss, 'mode','multiple')
            end

            Fig = app.SLIP;
            
            try
                C_InfoDetectedSoilSlips;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: TableCreationMenu
        function TableCreationMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_user');

            Fig = app.SLIP;

            try
                D_InfoDetectedSoilSlips_Parameters;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            drawnow;
            figure(app.SLIP)
        end

        % Menu selected function: FSMap
        function FSMapMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var','fold_res_fs','fold0','fold_fig');

            Fig = app.SLIP;

            try
                xyz_Plot_FSMap;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            drawnow;
            figure(app.SLIP)
        end

        % Menu selected function: PunctualMenu
        function PunctualMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var','fold_res_fs')

            Fig = app.SLIP;

            cd(fold_var)
            if exist('InfoDetectedSoilSlips.mat', 'file')
                try
                    xyz_Plot_FSRainPunctual;
                catch me
                    getReport(me)
                    uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                       'an error occurred!'], 'Generic Error')
                end
            else
                uialert(app.SLIP, ['No detected soil slip! ' ...
                                   'Please run Modelling -> Optional -> Detected SS'], ...
                                   'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SusceptibilityMenu
        function SusceptibilityMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            F_Susceptibility;
        end

        % Button pushed function: OtherfromLandUseButton
        function OtherfromLandUseButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            cd(fold_var)

            if exist('LandUsesVariables.mat')
                load('LandUsesVariables.mat', 'AllLandUnique')

                Choice = listdlg('PromptString',{'Select Land Use',''}, 'ListString',AllLandUnique);

                figure(app.SLIP)
                drawnow

                SelectionLandUse = string(AllLandUnique(Choice));

                app.UITableSelLandUse.Enable = 'on';

                data = SelectionLandUse';
                set(app.UITableSelLandUse, 'Data',data);
            else
                uialert(app.SLIP, 'No Land Uses have been imported', 'Error')
            end

        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');

            Fig = app.SLIP;

            DataInBox = get(app.UITableSelLandUse, 'Data');

            FileLandUseSelected = ~isempty(DataInBox);
            if FileLandUseSelected == 1
                SelectedLU4Dist = DataInBox;
            end

            FileRoadSelected = ~strcmp(app.RoadFileDropDown.Value,'');
            if FileRoadSelected == 1
                FileName_Road = app.RoadFileDropDown.Value;
            end

            if FileRoadSelected == 0 && FileLandUseSelected == 0
                uialert(app.SLIP, 'Roads or Land Uses have to be selected', 'Error')
            else
                C_DistancesToObjects;
                app.LampDist.Visible = 'on';
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RoadMenu
        function RoadMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            
            IndRoad=1;
            IndLandUse=0;
            xyz_Plot_Distance
        end

        % Menu selected function: SpecificLUMenu
        function SpecificLUMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            
            IndRoad=0;
            IndLandUse=1;
            xyz_Plot_Distance
        end

        % Button pushed function: RemoveExistingAssociationButton
        function RemoveExistingAssociationButtonPushed(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var')
            
            Answer = uiconfirm(app.SLIP, 'Are you sure to delete existing association?', ...
                               'Remove existing Vegetation Association', ...
                               'Options',{'Yes, thanks', 'No, for God!'}, ...
                               'DefaultOption',2);

            switch Answer
                case 'Yes, thanks'
                    cd(fold_var)
                    delete 'VegPolygonsStudyArea.mat';
                    delete 'VUDVCMapParameters.mat';
                    load('GridCoordinates.mat', 'xLongAll');
                    load('MorphologyParameters.mat', 'SlopeAll');
                    
                    SizeCoordinates = cellfun(@size, xLongAll, 'UniformOutput',false);
                    BetaStarAll = cellfun(@cosd, SlopeAll, 'UniformOutput',false); 
                    RootCohesionAll = cellfun(@zeros, SizeCoordinates, 'UniformOutput',false);
        
                    VariablesVegPar = {'RootCohesionAll', 'BetaStarAll'};
                    save('VegetationParameters.mat', VariablesVegPar{:});
                case 'No, for God!'
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SoilUnitsMenu
        function SoilUnitsMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold_var','fold0','fold_fig');
            NumFigPlot=4;
            xyz_Plot_SoilParameters;
        end

        % Menu selected function: RecordingTrendMenu
        function RecordingTrendMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            RainFallType=2;
            xyz_Plot_Rain;
        end

        % Menu selected function: RiskAssessmentMenu
        function RiskAssessmentMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            F_WeightedRiskAnalysis
        end

        % Menu selected function: FSClassificationMenu
        function FSClassificationMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders','fold0','fold_var','fold_res_fs','sl');

            Fig = app.SLIP;
            
            cd(fold_var)
            if exist('InfoDetectedSoilSlips.mat')
                Answer = uiconfirm(app.SLIP, ['If you made changes, you have to run again Table Creation ' ...
                                              '(go in: Modelling -> Optional)'], ...
                                   'Run Table Creation', ...
                                   'Options',{'Already done', 'Stop'}, ...
                                   'DefaultOption',2);

                if strcmp(Answer, 'Already done')
                    try
                        PostUserE_Step4_FS_MachineLearning
                    catch me
                        getReport(me)
                        uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                           'an error occurred!'], 'Generic Error')
                    end
                end

            else
                uialert(app.SLIP,['Machine Learning require to run Detected SS, ' ...
                                  'go in: Modelling -> Optional, and run both'],'Error');
            end
        end

        % Menu selected function: ROCCurveMenu
        function ROCCurveMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders');
            Fig = app.SLIP;
            F_ROC
        end

        % Menu selected function: PlotSettingsMenu
        function PlotSettingsMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_var');
            plot_settings(fold_var)
        end

        % Menu selected function: MunicipalitiesMenu
        function MunicipalitiesMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig');

            Fig = app.SLIP;

            try
                xyz_Plot_StudyArea;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: DrainageCoefficientMenu
        function DrainageCoefficientMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig');
            NumFigPlot=5;
            xyz_Plot_SoilParameters;  
        end

        % Menu selected function: AParameterMenu
        function AParameterMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig');
            NumFigPlot=6;
            xyz_Plot_SoilParameters;  
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            cd(app.FolderLabel.Text);
        end

        % Menu selected function: FSClassificationMenu_2
        function FSClassificationMenu_2Selected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_res_fs','sl');

            Fig = app.SLIP;

            try
                Hybrid_Analysis;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SoilPropertiesMenu
        function SoilPropertiesMenuSelected(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold0','fold_var','fold_fig','fold_raw','sl');

            Fig = app.SLIP;

            cd(fold_var)
            if exist('InfoDetectedSoilSlips.mat')
                Answer = uiconfirm(app.SLIP, ['If you made changes, you have to run again Table Creation ' ...
                                              '(go in: Modelling -> Optional)'], ...
                                   'Run Table Creation', ...
                                   'Options',{'Already done', 'Stop'}, ...
                                   'DefaultOption',2);

                if strcmp(Answer, 'Already done')
                    try
                        xyz_Plot_InfoDetected;
                    catch me
                        getReport(me)
                        uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                           'an error occurred!'], 'Generic Error')
                    end
                end

            else
                uialert(app.SLIP,['This function require to run Detected SS, ' ...
                                  'go in: Modelling -> Optional, and run both'],'Error');
            end

            figure(app.SLIP)
            drawnow
        end

        % Drop down opening function: RoadFileDropDown
        function RoadFileDropDownOpening(app, event)
            cd(app.FolderLabel.Text);
            load('os_folders', 'fold_raw_road')
            
            cd(fold_raw_road)

            if isempty({dir('*.shp').name})
                uiconfirm(app.SLIP, strcat("No vegetation shapefile in ",fold_raw_veg), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_road, 'mode','multiple')
            end

            figure(app.SLIP)
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLIP = uifigure('Visible', 'off');
            app.SLIP.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.SLIP);
            app.FileMenu.Text = 'File';
            app.NewAnalysisMenu = uimenu(app.FileMenu);
            app.NewAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @NewAnalysisMenuSelected, true);
            app.NewAnalysisMenu.Text = 'New Analysis';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ModellingMenu = uimenu(app.SLIP);
            app.ModellingMenu.Text = 'Modelling';
            app.OptionalMenu = uimenu(app.ModellingMenu);
            app.OptionalMenu.Text = 'Optional';
            app.DetectedSSMenu = uimenu(app.OptionalMenu);
            app.DetectedSSMenu.Text = 'Detected SS';
            app.FromExcelMenu = uimenu(app.DetectedSSMenu);
            app.FromExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @FromExcelMenuSelected, true);
            app.FromExcelMenu.Text = 'From Excel';
            app.TableCreationMenu = uimenu(app.DetectedSSMenu);
            app.TableCreationMenu.MenuSelectedFcn = createCallbackFcn(app, @TableCreationMenuSelected, true);
            app.TableCreationMenu.Text = 'Table Creation';
            app.SLIPMenu = uimenu(app.ModellingMenu);
            app.SLIPMenu.Text = 'SLIP';
            app.HandinitialSrMenu = uimenu(app.SLIPMenu);
            app.HandinitialSrMenu.MenuSelectedFcn = createCallbackFcn(app, @HandinitialSrMenuSelected, true);
            app.HandinitialSrMenu.Enable = 'off';
            app.HandinitialSrMenu.Text = 'H and initial Sr';
            app.mEvaluationMenu = uimenu(app.SLIPMenu);
            app.mEvaluationMenu.MenuSelectedFcn = createCallbackFcn(app, @mEvaluationMenuSelected, true);
            app.mEvaluationMenu.Enable = 'off';
            app.mEvaluationMenu.Text = 'm evaluation';
            app.FSevaluationMenu = uimenu(app.SLIPMenu);
            app.FSevaluationMenu.MenuSelectedFcn = createCallbackFcn(app, @FSevaluationMenuSelected, true);
            app.FSevaluationMenu.Text = 'FS evaluation';
            app.ROCCurveMenu = uimenu(app.SLIPMenu);
            app.ROCCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @ROCCurveMenuSelected, true);
            app.ROCCurveMenu.Text = 'ROC Curve';
            app.SusceptibilityMenu = uimenu(app.SLIPMenu);
            app.SusceptibilityMenu.MenuSelectedFcn = createCallbackFcn(app, @SusceptibilityMenuSelected, true);
            app.SusceptibilityMenu.Text = 'Susceptibility';
            app.RiskAssessmentMenu = uimenu(app.SLIPMenu);
            app.RiskAssessmentMenu.MenuSelectedFcn = createCallbackFcn(app, @RiskAssessmentMenuSelected, true);
            app.RiskAssessmentMenu.Text = 'Risk Assessment';
            app.MachineLearningMenu = uimenu(app.ModellingMenu);
            app.MachineLearningMenu.Text = 'Machine Learning';
            app.FSClassificationMenu = uimenu(app.MachineLearningMenu);
            app.FSClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @FSClassificationMenuSelected, true);
            app.FSClassificationMenu.Enable = 'off';
            app.FSClassificationMenu.Text = 'FS Classification';
            app.HybridMenu = uimenu(app.ModellingMenu);
            app.HybridMenu.Text = 'Hybrid';
            app.FSClassificationMenu_2 = uimenu(app.HybridMenu);
            app.FSClassificationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FSClassificationMenu_2Selected, true);
            app.FSClassificationMenu_2.Text = 'FS Classification';
            app.PlotMenu = uimenu(app.SLIP);
            app.PlotMenu.Text = 'Plot';
            app.SettingsMenu = uimenu(app.PlotMenu);
            app.SettingsMenu.Text = 'Settings';
            app.PlotSettingsMenu = uimenu(app.SettingsMenu);
            app.PlotSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotSettingsMenuSelected, true);
            app.PlotSettingsMenu.Text = 'Plot Settings';
            app.StudyAreaMenu = uimenu(app.PlotMenu);
            app.StudyAreaMenu.Enable = 'off';
            app.StudyAreaMenu.Text = 'Study Area';
            app.MunicipalitiesMenu = uimenu(app.StudyAreaMenu);
            app.MunicipalitiesMenu.MenuSelectedFcn = createCallbackFcn(app, @MunicipalitiesMenuSelected, true);
            app.MunicipalitiesMenu.Text = 'Municipalities';
            app.LandUseMenu = uimenu(app.PlotMenu);
            app.LandUseMenu.Enable = 'off';
            app.LandUseMenu.Text = 'Land Use';
            app.AllMenu = uimenu(app.LandUseMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Enable = 'off';
            app.AllMenu.Text = 'All';
            app.ExcludedMenu = uimenu(app.LandUseMenu);
            app.ExcludedMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludedMenuSelected, true);
            app.ExcludedMenu.Enable = 'off';
            app.ExcludedMenu.Text = 'Excluded';
            app.MorphologyMenu = uimenu(app.PlotMenu);
            app.MorphologyMenu.Enable = 'off';
            app.MorphologyMenu.Text = 'Morphology';
            app.ElevationMenu = uimenu(app.MorphologyMenu);
            app.ElevationMenu.MenuSelectedFcn = createCallbackFcn(app, @ElevationMenuSelected, true);
            app.ElevationMenu.Enable = 'off';
            app.ElevationMenu.Text = 'Elevation';
            app.SlopeMenu = uimenu(app.MorphologyMenu);
            app.SlopeMenu.MenuSelectedFcn = createCallbackFcn(app, @SlopeMenuSelected, true);
            app.SlopeMenu.Enable = 'off';
            app.SlopeMenu.Text = 'Slope';
            app.AspectMenu = uimenu(app.MorphologyMenu);
            app.AspectMenu.MenuSelectedFcn = createCallbackFcn(app, @AspectMenuSelected, true);
            app.AspectMenu.Enable = 'off';
            app.AspectMenu.Text = 'Aspect';
            app.SoilMenu = uimenu(app.PlotMenu);
            app.SoilMenu.Enable = 'off';
            app.SoilMenu.Text = 'Soil';
            app.LithologyMapMenu = uimenu(app.SoilMenu);
            app.LithologyMapMenu.MenuSelectedFcn = createCallbackFcn(app, @LithologyMapMenuSelected, true);
            app.LithologyMapMenu.Enable = 'off';
            app.LithologyMapMenu.Text = 'Lithology Map';
            app.EffectiveCohesionMenu = uimenu(app.SoilMenu);
            app.EffectiveCohesionMenu.MenuSelectedFcn = createCallbackFcn(app, @EffectiveCohesionMenuSelected, true);
            app.EffectiveCohesionMenu.Enable = 'off';
            app.EffectiveCohesionMenu.Text = 'Effective Cohesion';
            app.FrictionAngleMenu = uimenu(app.SoilMenu);
            app.FrictionAngleMenu.MenuSelectedFcn = createCallbackFcn(app, @FrictionAngleMenuSelected, true);
            app.FrictionAngleMenu.Enable = 'off';
            app.FrictionAngleMenu.Text = 'Friction Angle';
            app.DrainageCoefficientMenu = uimenu(app.SoilMenu);
            app.DrainageCoefficientMenu.MenuSelectedFcn = createCallbackFcn(app, @DrainageCoefficientMenuSelected, true);
            app.DrainageCoefficientMenu.Enable = 'off';
            app.DrainageCoefficientMenu.Text = 'Drainage Coefficient';
            app.AParameterMenu = uimenu(app.SoilMenu);
            app.AParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @AParameterMenuSelected, true);
            app.AParameterMenu.Enable = 'off';
            app.AParameterMenu.Text = 'A Parameter';
            app.SoilUnitsMenu = uimenu(app.SoilMenu);
            app.SoilUnitsMenu.MenuSelectedFcn = createCallbackFcn(app, @SoilUnitsMenuSelected, true);
            app.SoilUnitsMenu.Enable = 'off';
            app.SoilUnitsMenu.Text = 'Soil Units';
            app.VegetationMenu = uimenu(app.PlotMenu);
            app.VegetationMenu.Enable = 'off';
            app.VegetationMenu.Text = 'Vegetation';
            app.MapMenu = uimenu(app.VegetationMenu);
            app.MapMenu.MenuSelectedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapMenu.Enable = 'off';
            app.MapMenu.Text = 'Map';
            app.RootcohesionMenu = uimenu(app.VegetationMenu);
            app.RootcohesionMenu.MenuSelectedFcn = createCallbackFcn(app, @RootcohesionMenuSelected, true);
            app.RootcohesionMenu.Enable = 'off';
            app.RootcohesionMenu.Text = 'Root cohesion';
            app.ThroughfallMenu = uimenu(app.VegetationMenu);
            app.ThroughfallMenu.MenuSelectedFcn = createCallbackFcn(app, @ThroughfallMenuSelected, true);
            app.ThroughfallMenu.Enable = 'off';
            app.ThroughfallMenu.Text = 'Throughfall';
            app.DetectedSSMenu_2 = uimenu(app.PlotMenu);
            app.DetectedSSMenu_2.Text = 'Detected SS';
            app.SoilPropertiesMenu = uimenu(app.DetectedSSMenu_2);
            app.SoilPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SoilPropertiesMenuSelected, true);
            app.SoilPropertiesMenu.Text = 'Soil Properties';
            app.RainfallMenu = uimenu(app.PlotMenu);
            app.RainfallMenu.Enable = 'off';
            app.RainfallMenu.Text = 'Rainfall';
            app.RecordingsMenu = uimenu(app.RainfallMenu);
            app.RecordingsMenu.Text = 'Recordings';
            app.RecordingMapMenu_2 = uimenu(app.RecordingsMenu);
            app.RecordingMapMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RecordingMapMenuSelected, true);
            app.RecordingMapMenu_2.Text = 'Recording Map';
            app.RecordingTrendMenu = uimenu(app.RecordingsMenu);
            app.RecordingTrendMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordingTrendMenuSelected, true);
            app.RecordingTrendMenu.Text = 'Recording Trend';
            app.ForecastMapMenu = uimenu(app.RainfallMenu);
            app.ForecastMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ForecastMapMenuSelected, true);
            app.ForecastMapMenu.Text = 'Forecast Map';
            app.DistanceMenu = uimenu(app.PlotMenu);
            app.DistanceMenu.Text = 'Distance';
            app.RoadMenu = uimenu(app.DistanceMenu);
            app.RoadMenu.MenuSelectedFcn = createCallbackFcn(app, @RoadMenuSelected, true);
            app.RoadMenu.Text = 'Road';
            app.SpecificLUMenu = uimenu(app.DistanceMenu);
            app.SpecificLUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecificLUMenuSelected, true);
            app.SpecificLUMenu.Text = 'Specific LU';
            app.FSMenu = uimenu(app.PlotMenu);
            app.FSMenu.Text = 'FS';
            app.FSMap = uimenu(app.FSMenu);
            app.FSMap.MenuSelectedFcn = createCallbackFcn(app, @FSMapMenuSelected, true);
            app.FSMap.Text = 'Map';
            app.PunctualMenu = uimenu(app.FSMenu);
            app.PunctualMenu.MenuSelectedFcn = createCallbackFcn(app, @PunctualMenuSelected, true);
            app.PunctualMenu.Text = 'Punctual';
            app.Image = uiimage(app.SLIP);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '@Logo.png';
            app.SOILPARAMETERSButtonGroup = uibuttongroup(app.SLIP);
            app.SOILPARAMETERSButtonGroup.Title = 'SOIL PARAMETERS';
            app.SwitchSoil = uiswitch(app.SOILPARAMETERSButtonGroup, 'slider');
            app.SwitchSoil.Items = {'Uniform', 'Map'};
            app.SwitchSoil.ValueChangedFcn = createCallbackFcn(app, @SwitchSoilValueChanged, true);
            app.SwitchSoil.Enable = 'off';
            app.SwitchSoil.Value = 'Uniform';
            app.SelectFieldDropDownLabel = uilabel(app.SOILPARAMETERSButtonGroup);
            app.SelectFieldDropDownLabel.Enable = 'off';
            app.SelectFieldDropDownLabel.Text = 'Select Field';
            app.SelectFieldDropDown = uidropdown(app.SOILPARAMETERSButtonGroup);
            app.SelectFieldDropDown.Items = {};
            app.SelectFieldDropDown.Enable = 'off';
            app.SelectFieldDropDown.Value = {};
            app.RunLito = uibutton(app.SOILPARAMETERSButtonGroup, 'push');
            app.RunLito.ButtonPushedFcn = createCallbackFcn(app, @RunLitoPushed, true);
            app.RunLito.Enable = 'off';
            app.RunLito.Text = 'Run';
            app.ExcelcreatedLabel = uilabel(app.SOILPARAMETERSButtonGroup);
            app.ExcelcreatedLabel.Enable = 'off';
            app.ExcelcreatedLabel.Visible = 'off';
            app.ExcelcreatedLabel.Text = 'Excel created!';
            app.AssociationButton = uibutton(app.SOILPARAMETERSButtonGroup, 'push');
            app.AssociationButton.ButtonPushedFcn = createCallbackFcn(app, @AssociationButtonLithoPushed, true);
            app.AssociationButton.Visible = 'off';
            app.AssociationButton.Text = 'Association';
            app.FinishedLampSoil1 = uilamp(app.SOILPARAMETERSButtonGroup);
            app.FinishedLampSoil1.Visible = 'off';
            app.ChoosefileDropDownLabel_4 = uilabel(app.SOILPARAMETERSButtonGroup);
            app.ChoosefileDropDownLabel_4.Enable = 'off';
            app.ChoosefileDropDownLabel_4.Text = 'Choose file';
            app.ChoosefileLitDropDown = uidropdown(app.SOILPARAMETERSButtonGroup);
            app.ChoosefileLitDropDown.Items = {};
            app.ChoosefileLitDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChoosefileLitDropDownOpening, true);
            app.ChoosefileLitDropDown.Enable = 'off';
            app.ChoosefileLitDropDown.Value = {};
            app.STUDYAREAPanel = uipanel(app.SLIP);
            app.STUDYAREAPanel.Title = 'STUDY AREA';
            app.StudyAreaLabel = uilabel(app.STUDYAREAPanel);
            app.StudyAreaLabel.Enable = 'off';
            app.StudyAreaLabel.Text = 'Select file';
            app.SelectfileSADropDown = uidropdown(app.STUDYAREAPanel);
            app.SelectfileSADropDown.Items = {};
            app.SelectfileSADropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectfileSADropDownOpening, true);
            app.SelectfileSADropDown.Enable = 'off';
            app.SelectfileSADropDown.Value = {};
            app.FieldMunicipalityDropDownLabel = uilabel(app.STUDYAREAPanel);
            app.FieldMunicipalityDropDownLabel.Enable = 'off';
            app.FieldMunicipalityDropDownLabel.Text = 'Field Municipality';
            app.FieldMunicipalityDropDown = uidropdown(app.STUDYAREAPanel);
            app.FieldMunicipalityDropDown.Items = {};
            app.FieldMunicipalityDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldMunicipalityDropDownValueChanged, true);
            app.FieldMunicipalityDropDown.Enable = 'off';
            app.FieldMunicipalityDropDown.Value = {};
            app.RunSAButton = uibutton(app.STUDYAREAPanel, 'push');
            app.RunSAButton.ButtonPushedFcn = createCallbackFcn(app, @RunSAButtonPushed, true);
            app.RunSAButton.Enable = 'off';
            app.RunSAButton.Text = 'Run ';
            app.SelectMUNButton = uibutton(app.STUDYAREAPanel, 'push');
            app.SelectMUNButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMUNButtonPushed, true);
            app.SelectMUNButton.Enable = 'off';
            app.SelectMUNButton.Text = 'Select MUN';
            app.FinishedLampRun1 = uilamp(app.STUDYAREAPanel);
            app.FinishedLampRun1.Visible = 'off';
            app.WindowCheckBox = uicheckbox(app.STUDYAREAPanel);
            app.WindowCheckBox.Enable = 'off';
            app.WindowCheckBox.Text = 'Window';
            app.LANDUSEPanel = uipanel(app.SLIP);
            app.LANDUSEPanel.Title = 'LAND USE';
            app.SelectfileLabel = uilabel(app.LANDUSEPanel);
            app.SelectfileLabel.Enable = 'off';
            app.SelectfileLabel.Text = 'Select file';
            app.SelectfileLUDropDown = uidropdown(app.LANDUSEPanel);
            app.SelectfileLUDropDown.Items = {};
            app.SelectfileLUDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectfileLUDropDownOpening, true);
            app.SelectfileLUDropDown.Enable = 'off';
            app.SelectfileLUDropDown.Value = {};
            app.FieldLandUseDropDownLabel = uilabel(app.LANDUSEPanel);
            app.FieldLandUseDropDownLabel.Enable = 'off';
            app.FieldLandUseDropDownLabel.Text = 'Field Land Use ';
            app.FieldLandUseDropDown = uidropdown(app.LANDUSEPanel);
            app.FieldLandUseDropDown.Items = {};
            app.FieldLandUseDropDown.Enable = 'off';
            app.FieldLandUseDropDown.Value = {};
            app.ImportLUButton = uibutton(app.LANDUSEPanel, 'push');
            app.ImportLUButton.ButtonPushedFcn = createCallbackFcn(app, @ImportLUButtonPushed, true);
            app.ImportLUButton.Enable = 'off';
            app.ImportLUButton.Text = 'Import';
            app.FinishedLamp = uilamp(app.LANDUSEPanel);
            app.FinishedLamp.Visible = 'off';
            app.SelectButton = uibutton(app.LANDUSEPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLUButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.FinishedLamp_2 = uilamp(app.LANDUSEPanel);
            app.FinishedLamp_2.Visible = 'off';
            app.MORPHOLOGYPanel = uipanel(app.SLIP);
            app.MORPHOLOGYPanel.Title = 'MORPHOLOGY';
            app.ChangeResolutionCheckBox = uicheckbox(app.MORPHOLOGYPanel);
            app.ChangeResolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeResolutionCheckBoxValueChanged, true);
            app.ChangeResolutionCheckBox.Enable = 'off';
            app.ChangeResolutionCheckBox.Text = 'Change Resolution';
            app.EnterResolutionLabel = uilabel(app.MORPHOLOGYPanel);
            app.EnterResolutionLabel.Enable = 'off';
            app.EnterResolutionLabel.Text = {'Enter '; 'New Resolution'};
            app.EnterNewResolutionEditField = uieditfield(app.MORPHOLOGYPanel, 'numeric');
            app.EnterNewResolutionEditField.Editable = 'off';
            app.EnterNewResolutionEditField.Enable = 'off';
            app.RunDTMButton = uibutton(app.MORPHOLOGYPanel, 'push');
            app.RunDTMButton.ButtonPushedFcn = createCallbackFcn(app, @RunDTMButtonPushed, true);
            app.RunDTMButton.Enable = 'off';
            app.RunDTMButton.Text = 'Run ';
            app.LampMorph = uilamp(app.MORPHOLOGYPanel);
            app.LampMorph.Visible = 'off';
            app.SelectDTMfilesButton = uibutton(app.MORPHOLOGYPanel, 'push');
            app.SelectDTMfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDTMfilesButtonPushed, true);
            app.SelectDTMfilesButton.Enable = 'off';
            app.SelectDTMfilesButton.Text = 'Select DTM file (s)';
            app.OrthophotoCheckBox = uicheckbox(app.MORPHOLOGYPanel);
            app.OrthophotoCheckBox.Enable = 'off';
            app.OrthophotoCheckBox.Text = 'Orthophoto';
            app.VEGETATIONPARAMETERSPanel = uibuttongroup(app.SLIP);
            app.VEGETATIONPARAMETERSPanel.Enable = 'off';
            app.VEGETATIONPARAMETERSPanel.Title = 'VEGETATION PARAMETERS';
            app.SwitchVeg = uiswitch(app.VEGETATIONPARAMETERSPanel, 'slider');
            app.SwitchVeg.Items = {'Uniform', 'Map'};
            app.SwitchVeg.ValueChangedFcn = createCallbackFcn(app, @SwitchVegValueChanged, true);
            app.SwitchVeg.Value = 'Uniform';
            app.LandUseCheckBox = uicheckbox(app.VEGETATIONPARAMETERSPanel);
            app.LandUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LandUseCheckBoxValueChanged, true);
            app.LandUseCheckBox.Enable = 'off';
            app.LandUseCheckBox.Text = 'Land Use';
            app.RunVeg = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.RunVeg.ButtonPushedFcn = createCallbackFcn(app, @RunVegPushed, true);
            app.RunVeg.Text = 'Run';
            app.AssociationButtonVeg = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.AssociationButtonVeg.ButtonPushedFcn = createCallbackFcn(app, @AssociationButtonVegPushed, true);
            app.AssociationButtonVeg.Enable = 'off';
            app.AssociationButtonVeg.Text = 'Association';
            app.ExcelcreatedLabelVeg = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.ExcelcreatedLabelVeg.Visible = 'off';
            app.ExcelcreatedLabelVeg.Text = 'Excel created!';
            app.SelectFieldDropDown_2Label = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.SelectFieldDropDown_2Label.Enable = 'off';
            app.SelectFieldDropDown_2Label.Text = 'Select Field';
            app.SelectFieldVegDropDown = uidropdown(app.VEGETATIONPARAMETERSPanel);
            app.SelectFieldVegDropDown.Items = {};
            app.SelectFieldVegDropDown.Enable = 'off';
            app.SelectFieldVegDropDown.Value = {};
            app.FinishedLampVeg1 = uilamp(app.VEGETATIONPARAMETERSPanel);
            app.FinishedLampVeg1.Visible = 'off';
            app.ChoosefileDropDownLabel_3 = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.ChoosefileDropDownLabel_3.Enable = 'off';
            app.ChoosefileDropDownLabel_3.Text = 'Choose file';
            app.ChoosefileVegDropDown = uidropdown(app.VEGETATIONPARAMETERSPanel);
            app.ChoosefileVegDropDown.Items = {};
            app.ChoosefileVegDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChoosefileVegDropDownOpening, true);
            app.ChoosefileVegDropDown.Enable = 'off';
            app.ChoosefileVegDropDown.Value = {};
            app.RemoveExistingAssociationButton = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.RemoveExistingAssociationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveExistingAssociationButtonPushed, true);
            app.RemoveExistingAssociationButton.Text = {'Remove '; 'Existing '; 'Association'};
            app.RAINFALLPanel = uipanel(app.SLIP);
            app.RAINFALLPanel.Title = 'RAINFALL';
            app.InterpolationButton = uibutton(app.RAINFALLPanel, 'push');
            app.InterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationButtonPushed, true);
            app.InterpolationButton.Text = 'Interpolation';
            app.LampInterp = uilamp(app.RAINFALLPanel);
            app.LampInterp.Visible = 'off';
            app.SwitchRain = uiswitch(app.RAINFALLPanel, 'slider');
            app.SwitchRain.Items = {'SLIP', 'Other'};
            app.SwitchRain.Value = 'SLIP';
            app.RecCheckBox = uicheckbox(app.RAINFALLPanel);
            app.RecCheckBox.Text = 'Rec';
            app.ForecastCheckBox = uicheckbox(app.RAINFALLPanel);
            app.ForecastCheckBox.Text = 'Forecast';
            app.SelectfilesButton = uibutton(app.RAINFALLPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select file (s)';
            app.UITableMun = uitable(app.SLIP);
            app.UITableMun.ColumnName = {'Mun'};
            app.UITableMun.RowName = {};
            app.UITableLURem = uitable(app.SLIP);
            app.UITableLURem.ColumnName = {'Land Use Removed'};
            app.UITableLURem.RowName = {};
            app.UITableDTM = uitable(app.SLIP);
            app.UITableDTM.ColumnName = {'DTM'};
            app.UITableDTM.RowName = {};
            app.UITableLitho = uitable(app.SLIP);
            app.UITableLitho.ColumnName = {'Lithology'; 'DSC'};
            app.UITableLitho.RowName = {};
            app.CasestudyLabel = uilabel(app.SLIP);
            app.CasestudyLabel.Text = 'Case study: ';
            app.FolderLabel = uilabel(app.SLIP);
            app.FolderLabel.Text = 'Folder:';
            app.STARTButton = uibutton(app.SLIP, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.UITableVeg = uitable(app.SLIP);
            app.UITableVeg.ColumnName = {'Vegetation'; 'DVC'};
            app.UITableVeg.RowName = {};
            app.UITableRainfall = uitable(app.SLIP);
            app.UITableRainfall.ColumnName = {'Rainfall events'};
            app.UITableRainfall.RowName = {};
            app.DISTANCESPanel = uipanel(app.SLIP);
            app.DISTANCESPanel.Enable = 'off';
            app.DISTANCESPanel.Title = 'DISTANCES';
            app.RoadFileDropDownLabel = uilabel(app.DISTANCESPanel);
            app.RoadFileDropDownLabel.Enable = 'off';
            app.RoadFileDropDownLabel.Text = 'Road File';
            app.RoadFileDropDown = uidropdown(app.DISTANCESPanel);
            app.RoadFileDropDown.Items = {};
            app.RoadFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RoadFileDropDownOpening, true);
            app.RoadFileDropDown.Enable = 'off';
            app.RoadFileDropDown.Value = {};
            app.EvaluateButton = uibutton(app.DISTANCESPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.OtherfromLandUseButton = uibutton(app.DISTANCESPanel, 'push');
            app.OtherfromLandUseButton.ButtonPushedFcn = createCallbackFcn(app, @OtherfromLandUseButtonPushed, true);
            app.OtherfromLandUseButton.Enable = 'off';
            app.OtherfromLandUseButton.Text = {'Other from '; 'Land Use'};
            app.UITableSelLandUse = uitable(app.DISTANCESPanel);
            app.UITableSelLandUse.ColumnName = {'Selected Land Use'};
            app.UITableSelLandUse.RowName = {};
            app.UITableSelLandUse.Enable = 'off';
            app.LampDist = uilamp(app.DISTANCESPanel);
            app.LampDist.Visible = 'off';
            % Show the figure after all components are created
            app.SLIP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M_SLIP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLIP)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLIP)
        end
    end
end